classdef AppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LowPassFilterTab             matlab.ui.container.Tab
        LowPassFilterDropDown        matlab.ui.control.DropDown
        LowPassFilterDropDownLabel   matlab.ui.control.Label
        ImageResultLabel             matlab.ui.control.Label
        FourierFilterLabel           matlab.ui.control.Label
        FourierSpectrumLabel         matlab.ui.control.Label
        ImageInputLabel              matlab.ui.control.Label
        LoadimageButtonLPF           matlab.ui.control.Button
        ExampleimageLPF              matlab.ui.control.DropDown
        ExampleimageDropDownLabel    matlab.ui.control.Label
        resLPF                       matlab.ui.control.UIAxes
        hLPF                         matlab.ui.control.UIAxes
        fsLPF                        matlab.ui.control.UIAxes
        InputLPF                     matlab.ui.control.UIAxes
        HighPassFilterTab            matlab.ui.container.Tab
        HighPassFilterDropDown       matlab.ui.control.DropDown
        HighPassFilterLabel          matlab.ui.control.Label
        ExampleimageHPF              matlab.ui.control.DropDown
        ExampleimageDropDownLabel_2  matlab.ui.control.Label
        ImageResultLabel_2           matlab.ui.control.Label
        FourierFilterLabel_2         matlab.ui.control.Label
        FourierSpectrumLabel_2       matlab.ui.control.Label
        ImageInputLabel_2            matlab.ui.control.Label
        LoadimageButtonHPF           matlab.ui.control.Button
        resHPF                       matlab.ui.control.UIAxes
        hHPF                         matlab.ui.control.UIAxes
        fsHPF                        matlab.ui.control.UIAxes
        InputHPF                     matlab.ui.control.UIAxes
        ImageBrighteningTab          matlab.ui.container.Tab
        ImageInputLabel_3            matlab.ui.control.Label
        ImageResultLabel_3           matlab.ui.control.Label
        FourierFilterLabel_3         matlab.ui.control.Label
        FourierSpectrumLabel_3       matlab.ui.control.Label
        ImageInputIB                 matlab.ui.control.Label
        resHPF_2                     matlab.ui.control.UIAxes
        hHPF_2                       matlab.ui.control.UIAxes
        fsHPF_2                      matlab.ui.control.UIAxes
        InputHPF_2                   matlab.ui.control.UIAxes
        PeriodicnoiseremovalTab      matlab.ui.container.Tab
        ImageInputLabel_4            matlab.ui.control.Label
        ExampleimageHPF_3            matlab.ui.control.DropDown
        ExampleimageDropDownLabel_4  matlab.ui.control.Label
        ImageResultLabel_4           matlab.ui.control.Label
        FourierFilterLabel_4         matlab.ui.control.Label
        FourierSpectrumLabel_4       matlab.ui.control.Label
        ImageInputPNR                matlab.ui.control.Label
        resHPF_3                     matlab.ui.control.UIAxes
        hHPF_3                       matlab.ui.control.UIAxes
        fsHPF_3                      matlab.ui.control.UIAxes
        InputHPF_3                   matlab.ui.control.UIAxes
    end

    % Method Program
    methods (Access = private)
        function lowPassFilter(app, imagePath)
            f = imread(imagePath);
            imagesc(app.InputLPF, f);

            f =  im2double(f);
            [M,N,O] = size(f);

            P = 2*M;
            Q = 2*N;
            fp = zeros(P,Q,O);

            for i = 1:P
                for j = 1:Q
                    if i <= M && j<= N
                        fp(i,j,:) = f(i,j,:);
                    else
                        fp(i,j,:) = 0;
                    end
                end
            end

            Fc = fftshift(fft2(im2gray(fp)));
            S2 = log(1+abs(Fc));
            imagesc(app.fsLPF, S2);

            F = zeros(P,Q,O);
            for i = 1:O
                F(:,:,i) = fft2(double(fp(:,:,i)));
            end
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);
            
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);

            switch app.LowPassFilterDropDown.Value
                case 'Ideal'
                    H = double(D <= D0);
                case 'Gaussian'
                    H = exp(-(D.^2)./(2*(D0^2))); 
                case 'Butterworth'
                    H = 1./(1 + (D./D0).^2);
            end

            H = fftshift(H);
            imagesc(app.hLPF, H);

            H = ifftshift(H);
            LPF_f2 = zeros(P,Q,O);
            for i = 1:O
                LPF_f = H.*F(:,:,i);
                LPF_f2(:,:,i) = real(ifft2(LPF_f));
            end
            imagesc(app.resLPF, LPF_f2(1:M,1:N,:));
        end

        function highPassFilter(app, imagePath)
            f = imread(imagePath);
            imagesc(app.InputHPF, f);

            f =  im2double(f);
            [M,N,O] = size(f);

            P = 2*M;
            Q = 2*N;
            fp = zeros(P,Q,O);

            for i = 1:P
                for j = 1:Q
                    if i <= M && j<= N
                        fp(i,j,:) = f(i,j,:);
                    else
                        fp(i,j,:) = 0;
                    end
                end
            end

            Fc = fftshift(fft2(im2gray(fp)));
            S2 = log(1+abs(Fc));
            imagesc(app.fsHPF, S2);

            F = zeros(P,Q,O);
            for i = 1:O
                F(:,:,i) = fft2(double(fp(:,:,i)));
            end
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);
            
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);

            switch app.HighPassFilterDropDown.Value
                case 'Ideal'
                    H = double(D <= D0);
                case 'Gaussian'
                    H = exp(-(D.^2)./(2*(D0^2))); 
                case 'Butterworth'
                    H = 1./(1 + (D./D0).^2);
            end
        
            H = 1 - H;
            H = fftshift(H);
            imagesc(app.hHPF, H);

            H = ifftshift(H);
            HPF_f2 = zeros(P,Q,O);
            for i = 1:O
                HPF_f = H.*F(:,:,i);
                HPF_f2(:,:,i) = real(ifft2(HPF_f));
            end
            imagesc(app.resHPF, HPF_f2(1:M,1:N,:));
        end

        function imageBrightening(app, imagePath)
            f = imread(imagePath);
            imagesc(app.InputHPF_2, f);

            [M, N, O]  = size(f);
            f =  im2double(f);

            Ff = fft2(im2gray(f));
            Fc = fftshift(Ff);
            S2 = log(1+abs(Fc));
            imagesc(app.fsHPF_2, S2);

            h = [0 -1 0; -1 5 -1; 0 -1 0];
            Hf = fft2(double(h), M, N);
            Hd = fftshift(Hf);
            Sh = log(1 + abs(Hd));
            imagesc(app.hHPF_2, Sh);

            F2 = Hf.*Ff;
            imagesc(app.resHPF_2, ifft2(F2));
        end

        function spectrum = spectrumFourier(~, F)
            spectrum = abs(F);
            spectrum = log(spectrum+1);
        end
        
        function imageResult = noiseRemoval(app, image, threshold, center, verbose)
            image = rgb2gray(image);
            F = fft2(double(image));
            F1 = fftshift(F);
            S2 = log(1+abs(F1));
            imagesc(app.fsHPF_3,S2)
            
            F2 = spectrumFourier(app, F1);
            if verbose == 1
                figure, imagesc(F2); colormap(gray); 
                title('magnitude spectrum');
            end
            
            brightSpikes = F2 > threshold;
            if verbose == 1
                figure, imagesc(brightSpikes); colormap(gray); 
                title('bright spikes');
            end
            
            if center == 1
                brightSpikes(145:160, :) = 0;
            elseif center == 2
                brightSpikes(190:360, :) = 0;
                brightSpikes(:, 160:400) = 0;
            elseif center == 3
                brightSpikes(250:300, :) = 0;
            end
            
            F1(brightSpikes) = 0;
            imagesc(app.hHPF_3,brightSpikes);
            if verbose == 1
                figure, imagesc(spectrumFourier(app,F1)); colormap(gray); 
                title('filtered magnitude spectrum');
            end
            
            imageResult = uint8(real(ifft2(ifftshift(F1))));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputLPF.Visible = 'off';
            app.InputLPF.Colormap = gray(256);
            axis(app.InputLPF, 'image');

            app.fsLPF.Visible = 'off';
            app.fsLPF.Colormap = gray(8);
            axis(app.fsLPF, 'image');

            app.hLPF.Visible = 'off';
            app.hLPF.Colormap = gray(256);
            axis(app.hLPF, 'image');

            app.resLPF.Visible = 'off';
            app.resLPF.Colormap = gray(256);
            axis(app.resLPF, 'image');

            app.InputHPF.Visible = 'off';
            app.InputHPF.Colormap = gray(256);
            axis(app.InputHPF, 'image');

            app.fsHPF.Visible = 'off';
            app.fsHPF.Colormap = gray(8);
            axis(app.fsHPF, 'image');

            app.hHPF.Visible = 'off';
            app.hHPF.Colormap = gray(256);
            axis(app.hHPF, 'image');

            app.resHPF.Visible = 'off';
            app.resHPF.Colormap = gray(256);
            axis(app.resHPF, 'image');

            app.InputHPF_2.Visible = 'off';
            app.InputHPF_2.Colormap = gray(256);
            axis(app.InputHPF_2, 'image');

            app.fsHPF_2.Visible = 'off';
            app.fsHPF_2.Colormap = gray(8);
            axis(app.fsHPF_2, 'image');

            app.hHPF_2.Visible = 'off';
            app.hHPF_2.Colormap = gray(256);
            axis(app.hHPF_2, 'image');

            app.resHPF_2.Visible = 'off';
            app.resHPF_2.Colormap = gray(256);
            axis(app.resHPF_2, 'image');

            app.InputHPF_3.Visible = 'off';
            app.InputHPF_3.Colormap = gray(256);
            axis(app.InputHPF_3, 'image');

            app.fsHPF_3.Visible = 'off';
            app.fsHPF_3.Colormap = gray(8);
            axis(app.fsHPF_3, 'image');

            app.hHPF_3.Visible = 'off';
            app.hHPF_3.Colormap = gray(256);
            axis(app.hHPF_3, 'image');

            app.resHPF_3.Visible = 'off';
            app.resHPF_3.Colormap = gray(256);
            axis(app.resHPF_3, 'image');

            app.ExampleimageLPFValueChanged()
            app.ExampleimageHPFValueChanged()
            app.imageBrightening('images/tunnel.jpg');
            app.ExampleimageHPF_3ValueChanged()
        end

        % Value changed function: ExampleimageLPF
        function ExampleimageLPFValueChanged(app, event)
            value = app.ExampleimageLPF.Value;
            switch value
                case 'Gedung Sate'
                    lowPassFilter(app, 'images/task1 (1).jpg')
                case 'Kupu-Kupu'
                    lowPassFilter(app, 'images/task1 (2).jpg')
                case 'Kapal'
                    lowPassFilter(app, 'images/task1 (3).jpg')
                case 'HerryPoter'
                    lowPassFilter(app, 'images/task1 (4).jpg')
                case 'Saturnus'
                    lowPassFilter(app, 'images/task1 (5).jpg')
                case 'Wajah'
                    lowPassFilter(app, 'images/task1 (6).jpg')
                case 'Polisi'
                    lowPassFilter(app, 'images/task1 (7).jpg')
            end
        end

        % Button pushed function: LoadimageButtonLPF
        function LoadimageButtonLPFPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               lowPassFilter(app, fname);
            end
        end

        % Value changed function: LowPassFilterDropDown
        function LowPassFilterDropDownValueChanged(app, event)
            app.ExampleimageLPFValueChanged(event);
        end

        % Value changed function: ExampleimageHPF
        function ExampleimageHPFValueChanged(app, event)
            value = app.ExampleimageHPF.Value;
            switch value
                case 'Rumah'
                    highPassFilter(app, 'images/task2 (1).jpg')
                case 'Kucing'
                    highPassFilter(app, 'images/task2 (2).jpg')
                case 'Bakar'
                    highPassFilter(app, 'images/task2 (3).jpg')
                case 'Gajah Duduk'
                    highPassFilter(app, 'images/task2 (4).png')
            end
        end

        % Value changed function: HighPassFilterDropDown
        function HighPassFilterDropDownValueChanged(app, event)
            app.ExampleimageHPFValueChanged(event);
        end

        % Button pushed function: LoadimageButtonHPF
        function LoadimageButtonHPFPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               highPassFilter(app, fname);
            end
        end

        % Value changed function: ExampleimageHPF_3
        function ExampleimageHPF_3ValueChanged(app, event)
            value = app.ExampleimageHPF_3.Value;
            switch value
                case 'Lena1'
                    image = imread('images/noisy1.jpg');
                    filteredImage = noiseRemoval(app, image, 11.3, 1, 0);
                case 'Dekor'
                    image = imread('images/noisy2.jpg');
                    filteredImage = noiseRemoval(app, image, 8.5, 2, 0);
                case 'Lena2'
                    image = imread('images/noisy3.jpg');
                    filteredImage = noiseRemoval(app, image, 10.9, 3, 0);
            end
            imagesc(app.InputHPF_3,image)
            imagesc(app.resHPF_3, filteredImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LowPassFilterTab = uitab(app.TabGroup);
            app.LowPassFilterTab.Title = 'Low Pass Filter';
            app.InputLPF = uiaxes(app.LowPassFilterTab);
            app.InputLPF.XTick = [];
            app.InputLPF.YTick = [];
            app.fsLPF = uiaxes(app.LowPassFilterTab);
            app.fsLPF.XTick = [];
            app.fsLPF.YTick = [];
            app.hLPF = uiaxes(app.LowPassFilterTab);
            app.hLPF.XTick = [];
            app.hLPF.YTick = [];
            app.resLPF = uiaxes(app.LowPassFilterTab);
            app.resLPF.XTick = [];
            app.resLPF.YTick = [];
            app.ExampleimageDropDownLabel = uilabel(app.LowPassFilterTab);
            app.ExampleimageDropDownLabel.Text = 'Example image';
            app.ExampleimageLPF = uidropdown(app.LowPassFilterTab);
            app.ExampleimageLPF.Items = {'Gedung Sate', 'Kupu-Kupu', 'Kapal', 'HerryPoter', 'Saturnus', 'Wajah', 'Polisi'};
            app.ExampleimageLPF.ValueChangedFcn = createCallbackFcn(app, @ExampleimageLPFValueChanged, true);
            app.ExampleimageLPF.Value = 'Gedung Sate';
            app.LoadimageButtonLPF = uibutton(app.LowPassFilterTab, 'push');
            app.LoadimageButtonLPF.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonLPFPushed, true);
            app.LoadimageButtonLPF.Text = 'Load image';
            app.ImageInputLabel = uilabel(app.LowPassFilterTab);
            app.ImageInputLabel.Text = 'Image Input';
            app.FourierSpectrumLabel = uilabel(app.LowPassFilterTab);
            app.FourierSpectrumLabel.Text = 'Fourier Spectrum';
            app.FourierFilterLabel = uilabel(app.LowPassFilterTab);
            app.FourierFilterLabel.Text = 'Fourier Filter';
            app.ImageResultLabel = uilabel(app.LowPassFilterTab);
            app.ImageResultLabel.Text = 'Image Result';
            app.LowPassFilterDropDownLabel = uilabel(app.LowPassFilterTab);
            app.LowPassFilterDropDownLabel.Text = 'Low Pass Filter';
            app.LowPassFilterDropDown = uidropdown(app.LowPassFilterTab);
            app.LowPassFilterDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.LowPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterDropDownValueChanged, true);
            app.LowPassFilterDropDown.Value = 'Ideal';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High Pass Filter';
            app.InputHPF = uiaxes(app.HighPassFilterTab);
            app.InputHPF.XTick = [];
            app.InputHPF.YTick = [];
            app.fsHPF = uiaxes(app.HighPassFilterTab);
            app.fsHPF.XTick = [];
            app.fsHPF.YTick = [];
            app.hHPF = uiaxes(app.HighPassFilterTab);
            app.hHPF.XTick = [];
            app.hHPF.YTick = [];
            app.resHPF = uiaxes(app.HighPassFilterTab);
            app.resHPF.XTick = [];
            app.resHPF.YTick = [];
            app.LoadimageButtonHPF = uibutton(app.HighPassFilterTab, 'push');
            app.LoadimageButtonHPF.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonHPFPushed, true);
            app.LoadimageButtonHPF.Text = 'Load image';
            app.ImageInputLabel_2 = uilabel(app.HighPassFilterTab);
            app.ImageInputLabel_2.Text = 'Image Input';
            app.FourierSpectrumLabel_2 = uilabel(app.HighPassFilterTab);
            app.FourierSpectrumLabel_2.Text = 'Fourier Spectrum';
            app.FourierFilterLabel_2 = uilabel(app.HighPassFilterTab);
            app.FourierFilterLabel_2.Text = 'Fourier Filter';
            app.ImageResultLabel_2 = uilabel(app.HighPassFilterTab);
            app.ImageResultLabel_2.Text = 'Image Result';
            app.ExampleimageDropDownLabel_2 = uilabel(app.HighPassFilterTab);
            app.ExampleimageDropDownLabel_2.Text = 'Example image';
            app.ExampleimageHPF = uidropdown(app.HighPassFilterTab);
            app.ExampleimageHPF.Items = {'Rumah', 'Kucing', 'Bakar', 'Gajah Duduk'};
            app.ExampleimageHPF.ValueChangedFcn = createCallbackFcn(app, @ExampleimageHPFValueChanged, true);
            app.ExampleimageHPF.Value = 'Rumah';
            app.HighPassFilterLabel = uilabel(app.HighPassFilterTab);
            app.HighPassFilterLabel.Text = 'High Pass Filter';
            app.HighPassFilterDropDown = uidropdown(app.HighPassFilterTab);
            app.HighPassFilterDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.HighPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterDropDownValueChanged, true);
            app.HighPassFilterDropDown.Value = 'Ideal';
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.InputHPF_2 = uiaxes(app.ImageBrighteningTab);
            app.InputHPF_2.XTick = [];
            app.InputHPF_2.YTick = [];
            app.fsHPF_2 = uiaxes(app.ImageBrighteningTab);
            app.fsHPF_2.XTick = [];
            app.fsHPF_2.YTick = [];
            app.hHPF_2 = uiaxes(app.ImageBrighteningTab);
            app.hHPF_2.XTick = [];
            app.hHPF_2.YTick = [];
            app.resHPF_2 = uiaxes(app.ImageBrighteningTab);
            app.resHPF_2.XTick = [];
            app.resHPF_2.YTick = [];
            app.ImageInputIB = uilabel(app.ImageBrighteningTab);
            app.ImageInputIB.Text = 'Image Input';
            app.FourierSpectrumLabel_3 = uilabel(app.ImageBrighteningTab);
            app.FourierSpectrumLabel_3.Text = 'Fourier Spectrum';
            app.FourierFilterLabel_3 = uilabel(app.ImageBrighteningTab);
            app.FourierFilterLabel_3.Text = 'Fourier Filter';
            app.ImageResultLabel_3 = uilabel(app.ImageBrighteningTab);
            app.ImageResultLabel_3.Text = 'Image Result';
            app.ImageInputLabel_3 = uilabel(app.ImageBrighteningTab);
            app.ImageInputLabel_3.Text = 'Image Input';
            app.PeriodicnoiseremovalTab = uitab(app.TabGroup);
            app.PeriodicnoiseremovalTab.Title = 'Periodic noise removal';
            app.InputHPF_3 = uiaxes(app.PeriodicnoiseremovalTab);
            app.InputHPF_3.XTick = [];
            app.InputHPF_3.YTick = [];
            app.fsHPF_3 = uiaxes(app.PeriodicnoiseremovalTab);
            app.fsHPF_3.XTick = [];
            app.fsHPF_3.YTick = [];
            app.hHPF_3 = uiaxes(app.PeriodicnoiseremovalTab);
            app.hHPF_3.XTick = [];
            app.hHPF_3.YTick = [];
            app.resHPF_3 = uiaxes(app.PeriodicnoiseremovalTab);
            app.resHPF_3.XTick = [];
            app.resHPF_3.YTick = [];
            app.ImageInputPNR = uilabel(app.PeriodicnoiseremovalTab);
            app.ImageInputPNR.Text = 'Image Input';
            app.FourierSpectrumLabel_4 = uilabel(app.PeriodicnoiseremovalTab);
            app.FourierSpectrumLabel_4.Text = 'Fourier Spectrum';
            app.FourierFilterLabel_4 = uilabel(app.PeriodicnoiseremovalTab);
            app.FourierFilterLabel_4.Text = 'Fourier Filter';
            app.ImageResultLabel_4 = uilabel(app.PeriodicnoiseremovalTab);
            app.ImageResultLabel_4.Text = 'Image Result';
            app.ExampleimageDropDownLabel_4 = uilabel(app.PeriodicnoiseremovalTab);
            app.ExampleimageDropDownLabel_4.Text = 'Example image';
            app.ExampleimageHPF_3 = uidropdown(app.PeriodicnoiseremovalTab);
            app.ExampleimageHPF_3.Items = {'Lena1', 'Dekor', 'Lena2'};
            app.ExampleimageHPF_3.ValueChangedFcn = createCallbackFcn(app, @ExampleimageHPF_3ValueChanged, true);
            app.ExampleimageHPF_3.Value = 'Lena1';
            app.ImageInputLabel_4 = uilabel(app.PeriodicnoiseremovalTab);
            app.ImageInputLabel_4.Text = 'Image Input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end